classdef interfaz2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Button_2                      matlab.ui.control.Button
        ID                            matlab.ui.control.NumericEditField
        IDparticipanteEditFieldLabel  matlab.ui.control.Label
        ES_7                          matlab.ui.control.Label
        ECV                           matlab.ui.control.Label
        fisica                        matlab.ui.control.Label
        ES_6                          matlab.ui.control.Label
        ES_5                          matlab.ui.control.Label
        ES_4                          matlab.ui.control.Label
        ES_3                          matlab.ui.control.Label
        DatosadicionalesButton        matlab.ui.control.Button
        alcohol                       matlab.ui.control.Label
        fumador                       matlab.ui.control.Label
        edades                        matlab.ui.control.Label
        mmHg                          matlab.ui.control.Label
        DATO_presion                  matlab.ui.control.Label
        ES_2                          matlab.ui.control.Label
        Presionarteriarmedia          matlab.ui.control.Button
        DATO_2                        matlab.ui.control.Label
        DATO                          matlab.ui.control.Label
        ES                            matlab.ui.control.Label
        calcularIMCButton             matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        grafica                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        HeartData %tabla con datos
    end
    
    
    methods (Access = private)
        
        function preprocesamiento(app)
            app.HeartData.height=app.HeartData.height/100;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load ("tabla.mat","heart_data")
            app.HeartData=heart_data;
            preprocesamiento(app);


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
        cla(app.grafica,"reset");
        concardioIMC = zeros(1, length(app.HeartData.age));
        sincardioIMC = zeros(1, length(app.HeartData.age));
        concardioCol = zeros(1, length(app.HeartData.age));
        sincardioCol = zeros(1, length(app.HeartData.age));
        IMC2 = zeros(1, length(app.HeartData.age));
        
        
        for i = 1:length(app.HeartData.age)

            peso=app.HeartData.weight(i);
            altura=app.HeartData.height(i);
            IMC2(i) = peso/altura^2;

            if app.HeartData.cardio(i)==1
                concardioIMC(i)=concardioIMC(i)+IMC2(i);
                concardioCol(i)=concardioCol(i)+ app.HeartData.cholesterol(i);
            elseif app.HeartData.cardio(i)==0
                sincardioIMC(i)=sincardioIMC(i)+IMC2(i);
                sincardioCol(i)=sincardioCol(i)+app.HeartData.cholesterol(i);
            end
        end
        
        figure;
        scatter(app.grafica,concardioIMC, concardioCol,'MarkerFaceColor', 'g');
        hold(app.grafica,'on');
        scatter(app.grafica,sincardioIMC, sincardioCol,'MarkerFaceColor', 'r');
        hold(app.grafica,'on');
        set(get(app.grafica(1),'xlabel'),'string','IMC');
        set(get(app.grafica(1),'ylabel'),'string','COLESTEROL');
        set(get(app.grafica(1),'title'),'string',' IMC VS COLESTEROL');
        
        legend(app.grafica,'Con enfermedad cardíaca','Sin enfermedad cardiaca');
        
    
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
        cla(app.grafica,"reset");
            concardioGlu = zeros(1, length(app.HeartData.age));
        sincardioGlu = zeros(1, length(app.HeartData.age));
        concardioPres = zeros(1, length(app.HeartData.age));
        sincardioPres = zeros(1, length(app.HeartData.age));
        PresionMedia = zeros(1, length(app.HeartData.age));

        for i = 1:length(app.HeartData.age)
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            PresionMedia(i)=(((presionsis)+(2*presiondias))/(3));

            if app.HeartData.cardio(i)==1
                concardioGlu(i)=concardioGlu(i)+app.HeartData.gluc(i);
                concardioPres(i)=concardioPres(i)+PresionMedia(i);
            elseif app.HeartData.cardio(i)==0
                sincardioGlu(i)=sincardioGlu(i)+app.HeartData.gluc(i);
                sincardioPres(i)=sincardioPres(i)+PresionMedia(i);
            end
        end
        
figure;
        scatter(app.grafica,concardioGlu, concardioPres, 'MarkerFaceColor', 'b');
        hold(app.grafica,'on');
        scatter(app.grafica,sincardioGlu, sincardioPres, 'MarkerFaceColor', 'r');
        hold(app.grafica,'on');
        set(get(app.grafica(1),'xlabel'),'string','GLUCOSA');
        set(get(app.grafica(1),'ylabel'),'string','PRESION ARTERIAL MEDIA');
        set(get(app.grafica(1),'title'),'string',' GLUCOSA VS PRESION ARTERIAL');
        
        legend(app.grafica,'Con enfermedad cardíaca','Sin enfermedad cardiaca');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.calcularIMCButton = uibutton(app.UIFigure, 'push');
            app.calcularIMCButton.Text = 'calcular IMC';
            app.ES = uilabel(app.UIFigure);
            app.ES.Text = 'Es:';
            app.DATO = uilabel(app.UIFigure);
            app.DATO.Text = '';
            app.DATO_2 = uilabel(app.UIFigure);
            app.DATO_2.Text = '';
            app.Presionarteriarmedia = uibutton(app.UIFigure, 'push');
            app.Presionarteriarmedia.Text = 'calcular PAM';
            app.ES_2 = uilabel(app.UIFigure);
            app.ES_2.Text = 'Es:';
            app.DATO_presion = uilabel(app.UIFigure);
            app.DATO_presion.Text = '';
            app.mmHg = uilabel(app.UIFigure);
            app.mmHg.Text = '';
            app.edades = uilabel(app.UIFigure);
            app.edades.Text = '';
            app.fumador = uilabel(app.UIFigure);
            app.fumador.Text = '';
            app.alcohol = uilabel(app.UIFigure);
            app.alcohol.Text = '';
            app.DatosadicionalesButton = uibutton(app.UIFigure, 'push');
            app.DatosadicionalesButton.Text = 'Datos adicionales';
            app.ES_3 = uilabel(app.UIFigure);
            app.ES_3.Text = 'Edad:';
            app.ES_4 = uilabel(app.UIFigure);
            app.ES_4.Text = 'Fuma:';
            app.ES_5 = uilabel(app.UIFigure);
            app.ES_5.Text = 'Alcohol';
            app.ES_6 = uilabel(app.UIFigure);
            app.ES_6.Text = 'Actividad fisica:';
            app.fisica = uilabel(app.UIFigure);
            app.fisica.Text = '';
            app.ECV = uilabel(app.UIFigure);
            app.ECV.Text = '';
            app.ES_7 = uilabel(app.UIFigure);
            app.ES_7.Text = 'ECV:';
            app.IDparticipanteEditFieldLabel = uilabel(app.UIFigure);
            app.IDparticipanteEditFieldLabel.Text = 'ID participante';
            app.ID = uieditfield(app.UIFigure, 'numeric');
            app.ID.Limits = [1 7000];
            app.ID.ValueDisplayFormat = '%.0f';
            app.ID.Placeholder = '1';
            app.ID.Value = 1;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end