classdef interfzIMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BOTONGRAFICAR_2               matlab.ui.control.Button
        ES_7                          matlab.ui.control.Label
        ECV                           matlab.ui.control.Label
        fisica                        matlab.ui.control.Label
        ES_6                          matlab.ui.control.Label
        ES_5                          matlab.ui.control.Label
        ES_4                          matlab.ui.control.Label
        ES_3                          matlab.ui.control.Label
        DatosadicionalesButton        matlab.ui.control.Button
        TipodegraficaDropDown         matlab.ui.control.DropDown
        TipodegraficaDropDownLabel    matlab.ui.control.Label
        ID                            matlab.ui.control.NumericEditField
        IDparticipanteEditFieldLabel  matlab.ui.control.Label
        alcohol                       matlab.ui.control.Label
        fumador                       matlab.ui.control.Label
        edades                        matlab.ui.control.Label
        mmHg                          matlab.ui.control.Label
        DATO_presion                  matlab.ui.control.Label
        ES_2                          matlab.ui.control.Label
        Presionarteriarmedia          matlab.ui.control.Button
        DATO_2                        matlab.ui.control.Label
        DATO                          matlab.ui.control.Label
        ES                            matlab.ui.control.Label
        calcularIMCButton             matlab.ui.control.Button
        BOTONGRAFICAR                 matlab.ui.control.Button
        TITULO                        matlab.ui.control.Label
        grafica                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        HeartData %tabla con datos
    end
    
    methods (Access = private)
        
        function preprocesamiento(app)
            app.HeartData.height=app.HeartData.height/100;
        end
        function edad(app)
         app.HeartData.age=app.HeartData.age/365;
        end

        function alco(app)
        app.HeartData.alco = categorical(app.HeartData.alco, [0 1], {'No toma','Si toma'});

         end
        

        function fumar(app)
        app.HeartData.smoke = categorical(app.HeartData.smoke, [0 1], {'No fuma', 'Si Fuma'});

        end

        function actividad(app)
        app.HeartData.active = categorical(app.HeartData.active, [0 1], {'No hace actividad fisica', 'Si hace actividad fisica'});

        end
        
        function cardio(app)
        app.HeartData.cardio = categorical(app.HeartData.cardio, [0 1], {'No tiene','Si tiene'});

        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load ("tabla.mat","heart_data")
            app.HeartData=heart_data;
            preprocesamiento(app);
            edad(app);
            alco(app);
            fumar(app);
            actividad(app);
            cardio(app);
           

        end

        % Callback function
        function TipodegraficaDropDownValueChanged(app, event)
            
        end

        % Button pushed function: BOTONGRAFICAR
        function BOTONGRAFICARButtonPushed(app, event)
            hombreedad = zeros(1,6);
             mujeredad = zeros(1,6);


    for i = 1:length(app.HeartData.age)
        if strcmp(app.HeartData.cardio(i), 'Tiene una condicion cardiaca')
            if app.HeartData.age(i) <= 20
                if app.HeartData.gender(i) == 1
                    hombreedad(1) = hombreedad(1) + 1;
                elseif app.HeartData.gender(i) == 2
                    mujeredad(1) = mujeredad(1) + 1;
                end
            elseif (20<=app.HeartData.age(i))&&(app.HeartData.age(i)<=40)
                if app.HeartData.gender(i) == 1
                    hombreedad(2) = hombreedad(2) + 1;
                elseif app.HeartData.gender(i) == 2
                    mujeredad(2) = mujeredad(2) + 1;
                end
             elseif (40<=app.HeartData.age(i))&&(app.HeartData.age(i)<=60)
                if app.HeartData.gender(i) == 1
                    hombreedad(3) = hombreedad(3) + 1;
                elseif app.HeartData.gender(i) == 2
                    mujeredad(3) = mujeredad(3) + 1;
                end
             elseif (60<=app.HeartData.age(i))&&(app.HeartData.age(i)<=80)
                if app.HeartData.gender(i) == 1
                    hombreedad(4) = hombreedad(4) + 1;
                elseif app.HeartData.gender(i) == 2
                    mujeredad(4) = mujeredad(4) + 1;
                end
             elseif (80<=app.HeartData.age(i))&&(app.HeartData.age(i)<=100)
                if app.HeartData.gender(i) == 1
                    hombreedad(5) = hombreedad(5) + 1;
                elseif app.HeartData.gender(i) == 2
                    mujeredad(5) = mujeredad(5) + 1;
                end
             elseif app.HeartData.age(i) >= 100
                if app.HeartData.gender(i) == 1
                    hombreedad(6) = hombreedad(6) + 1;
                elseif app.HeartData.gender(i) == 2
                    mujeredad(6) = mujeredad(6) + 1;
                end
            end
        end
    end
    bar(app.grafica,hombreedad,mujeredad)


        end

        % Button pushed function: calcularIMCButton
        function calcularIMCButtonPushed(app, event)
             id=app.ID.Value;
            peso=app.HeartData.weight(id);
            altura=app.HeartData.height(id);
            imc=peso/altura^2;
            if (imc<16)
            app.DATO.Text=(num2str(imc,"%.2f"));
            app.DATO_2.Text=("delgadez severa");

            elseif (imc >= 16) && (imc <= 16.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("delgadez moderada");
             
            elseif (imc >= 17) && (imc <= 18.49)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("delgadez leve");

             elseif (imc >= 18.5) && (imc <= 24.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Normal");

             elseif (imc >= 25) && (imc <= 29.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Preobeso");

             elseif (imc >= 30) && (imc <= 34.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Obesidad leve");

             elseif (imc >= 35) && (imc <= 39.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Obesidad media");

             elseif (imc >= 40)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Obesidad morbida");

            end
        end

        % Button pushed function: Presionarteriarmedia
        function PresionarteriarmediaButtonPushed(app, event)
            id=app.ID.Value;
            presionsis=app.HeartData.ap_hi(id);
            presiondias=app.HeartData.ap_lo(id);
            presionmedia=(((presionsis)+(2*presiondias))/(3));
            app.DATO_presion.Text=(num2str(presionmedia,"%.2f"));
            app.mmHg.Text=("mmHg");

        end

        % Button pushed function: DatosadicionalesButton
        function DatosadicionalesButtonPushed(app, event)
            id=app.ID.Value;
            
            edad=(app.HeartData.age(id));
              app.edades.Text=(num2str(edad,"%.0f"));

            alco=(app.HeartData.alco(id));
              app.alcohol.Text=(string(alco));

             fuma=(app.HeartData.smoke(id));
              app.fumador.Text=(string(fuma));

             fisic=(app.HeartData.active(id));
             app.fisica.Text=(string(fisic));

             ecv=(app.HeartData.cardio(id));
             app.ECV.Text=(string(ecv));


        end

        % Button pushed function: BOTONGRAFICAR_2
        function BOTONGRAFICAR_2ButtonPushed(app, event)
            concardioGlu = zeros(1, length(app.HeartData.age));
        sincardioGlu = zeros(1, length(app.HeartData.age));
        concardioPres = zeros(1, length(app.HeartData.age));
        sincardioPres = zeros(1, length(app.HeartData.age));
        
        for i = 1:length(app.HeartData.age)
            imc(i)==(((app.HeartData.ap_hi(i))+((app.HeartData.ap_lo(i))*2))/3);

            if app.HeartData.cardio(i)==1
                concardioGlu(i)=concardioGlu(i)+app.HeartData.gluc(i);
                concardioPres(i)=concardioPres(i)+imc2(i);
            elseif app.HeartData.cardio(i)==0
                sincardioGlu(i)=sincardioGlu(i)+app.HeartData.gluc(i);
                sincardioPres(i)=sincardioPres(i)+imc(i);
            end
        end
        scatter(app.grafica,concardioGlu, concardioPres)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            app.TITULO = uilabel(app.UIFigure);
            app.TITULO.Text = 'Analisis indice masa corporal';
            app.BOTONGRAFICAR = uibutton(app.UIFigure, 'push');
            app.BOTONGRAFICAR.ButtonPushedFcn = createCallbackFcn(app, @BOTONGRAFICARButtonPushed, true);
            app.BOTONGRAFICAR.Text = 'GRAFICAR';
            app.calcularIMCButton = uibutton(app.UIFigure, 'push');
            app.calcularIMCButton.ButtonPushedFcn = createCallbackFcn(app, @calcularIMCButtonPushed, true);
            app.calcularIMCButton.Text = 'calcular IMC';
            app.ES = uilabel(app.UIFigure);
            app.ES.Text = 'Es:';
            app.DATO = uilabel(app.UIFigure);
            app.DATO.Text = '';
            app.DATO_2 = uilabel(app.UIFigure);
            app.DATO_2.Text = '';
            app.Presionarteriarmedia = uibutton(app.UIFigure, 'push');
            app.Presionarteriarmedia.ButtonPushedFcn = createCallbackFcn(app, @PresionarteriarmediaButtonPushed, true);
            app.Presionarteriarmedia.Text = 'calcular PAM';
            app.ES_2 = uilabel(app.UIFigure);
            app.ES_2.Text = 'Es:';
            app.DATO_presion = uilabel(app.UIFigure);
            app.DATO_presion.Text = '';
            app.mmHg = uilabel(app.UIFigure);
            app.mmHg.Text = '';
            app.edades = uilabel(app.UIFigure);
            app.edades.Text = '';
            app.fumador = uilabel(app.UIFigure);
            app.fumador.Text = '';
            app.alcohol = uilabel(app.UIFigure);
            app.alcohol.Text = '';
            app.IDparticipanteEditFieldLabel = uilabel(app.UIFigure);
            app.IDparticipanteEditFieldLabel.Text = 'ID participante';
            app.ID = uieditfield(app.UIFigure, 'numeric');
            app.ID.Limits = [1 7000];
            app.ID.ValueDisplayFormat = '%.0f';
            app.ID.Placeholder = '1';
            app.ID.Value = 1;
            app.TipodegraficaDropDownLabel = uilabel(app.UIFigure);
            app.TipodegraficaDropDownLabel.Text = 'Tipo de grafica';
            app.TipodegraficaDropDown = uidropdown(app.UIFigure);
            app.TipodegraficaDropDown.Items = {'Grafica de barras - hombres y mujeres', 'Gráfica de dispersión - Índice de masa corporal vs Colesterol', 'Gráfica de dispersión - Nivel de glucosa vs Presión arterial media'};
            app.TipodegraficaDropDown.ItemsData = [1 2 3];
            app.TipodegraficaDropDown.Value = 1;
            app.DatosadicionalesButton = uibutton(app.UIFigure, 'push');
            app.DatosadicionalesButton.ButtonPushedFcn = createCallbackFcn(app, @DatosadicionalesButtonPushed, true);
            app.DatosadicionalesButton.Text = 'Datos adicionales';
            app.ES_3 = uilabel(app.UIFigure);
            app.ES_3.Text = 'Edad:';
            app.ES_4 = uilabel(app.UIFigure);
            app.ES_4.Text = 'Fuma:';
            app.ES_5 = uilabel(app.UIFigure);
            app.ES_5.Text = 'Alcohol';
            app.ES_6 = uilabel(app.UIFigure);
            app.ES_6.Text = 'Actividad fisica:';
            app.fisica = uilabel(app.UIFigure);
            app.fisica.Text = '';
            app.ECV = uilabel(app.UIFigure);
            app.ECV.Text = '';
            app.ES_7 = uilabel(app.UIFigure);
            app.ES_7.Text = 'ECV:';
            app.BOTONGRAFICAR_2 = uibutton(app.UIFigure, 'push');
            app.BOTONGRAFICAR_2.ButtonPushedFcn = createCallbackFcn(app, @BOTONGRAFICAR_2ButtonPushed, true);
            app.BOTONGRAFICAR_2.Text = 'GRAFICAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfzIMC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end